classdef view < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel_2                 matlab.ui.container.Panel
        PodwjnewahadoLabel      matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        K2EditField_2           matlab.ui.control.NumericEditField
        Kt2radLabel             matlab.ui.control.Label
        K1EditField_4           matlab.ui.control.NumericEditField
        Kt1radLabel             matlab.ui.control.Label
        GEditField_3            matlab.ui.control.NumericEditField
        Przyspieszeniems2Label  matlab.ui.control.Label
        D2EditField             matlab.ui.control.NumericEditField
        Dugo2mEditFieldLabel    matlab.ui.control.Label
        D1EditField             matlab.ui.control.NumericEditField
        Dugo1mEditFieldLabel    matlab.ui.control.Label
        MaxTimeEditField        matlab.ui.control.NumericEditField
        CzastrwaniaanimacjisEditFieldLabel  matlab.ui.control.Label
        M1EditField             matlab.ui.control.NumericEditField
        MasadrugiejkulikgEditFieldLabel  matlab.ui.control.Label
        M2EditField             matlab.ui.control.NumericEditField
        MasapierwszejkulikgEditFieldLabel  matlab.ui.control.Label
        PokaanimacjeButton      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PokaanimacjeButton
        function PokaanimacjeButtonPushed(app, event)

            if app.K1EditField_4.Value == 0 || app.K2EditField_2.Value == 0 || app.M1EditField.Value == 0 || app.M2EditField.Value == 0 || app.D1EditField.Value == 0 || app.D2EditField.Value == 0 || app.GEditField_3.Value == 0 || app.MaxTimeEditField.Value == 0
                uialert(app.UIFigure,"Złe wartości","Komunikat");
            else
                pendulum = Pendulum(app.K1EditField_4.Value*pi/180, app.K2EditField_2.Value*pi/180, app.M1EditField.Value, app.M2EditField.Value, app.D1EditField.Value, app.D2EditField.Value, app.GEditField_3.Value, app.MaxTimeEditField.Value);
                animator = Animator(pendulum);
                animator = animator.animate();
                animator.save(); 
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %delete(app)
            sel = uiconfirm(app.UIFigure,"Czy na pewno chcesz wyjść?","Podwójne wahadło",'Options',{'Tak','Nie'});
            if strcmpi(sel,'Tak')
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.PokaanimacjeButton = uibutton(app.Panel, 'push');
            app.PokaanimacjeButton.ButtonPushedFcn = createCallbackFcn(app, @PokaanimacjeButtonPushed, true);
            app.PokaanimacjeButton.Text = 'Pokaż animacje';
            app.MasapierwszejkulikgEditFieldLabel = uilabel(app.Panel);
            app.MasapierwszejkulikgEditFieldLabel.Text = 'Masa pierwszej kuli (kg)';
            app.M2EditField = uieditfield(app.Panel, 'numeric');
            app.MasadrugiejkulikgEditFieldLabel = uilabel(app.Panel);
            app.MasadrugiejkulikgEditFieldLabel.Text = 'Masa drugiej kuli (kg)';
            app.M1EditField = uieditfield(app.Panel, 'numeric');
            app.CzastrwaniaanimacjisEditFieldLabel = uilabel(app.Panel);
            app.CzastrwaniaanimacjisEditFieldLabel.Text = 'Czas trwania animacji (s)';
            app.MaxTimeEditField = uieditfield(app.Panel, 'numeric');
            app.Dugo1mEditFieldLabel = uilabel(app.Panel);
            app.Dugo1mEditFieldLabel.Text = 'Długość 1 (m)';
            app.D1EditField = uieditfield(app.Panel, 'numeric');
            app.Dugo2mEditFieldLabel = uilabel(app.Panel);
            app.Dugo2mEditFieldLabel.Text = 'Długość 2 (m)';
            app.D2EditField = uieditfield(app.Panel, 'numeric');
            app.Przyspieszeniems2Label = uilabel(app.Panel);
            app.Przyspieszeniems2Label.Text = 'Przyspieszenie (m/s^2)';
            app.GEditField_3 = uieditfield(app.Panel, 'numeric');
            app.Kt1radLabel = uilabel(app.Panel);
            app.Kt1radLabel.Text = 'Kąt 1 (∘)';
            app.K1EditField_4 = uieditfield(app.Panel, 'numeric');
            app.Kt2radLabel = uilabel(app.Panel);
            app.Kt2radLabel.Text = 'Kąt 2 (∘)';
            app.K2EditField_2 = uieditfield(app.Panel, 'numeric');
            app.Panel_2 = uipanel(app.UIFigure);
            app.PodwjnewahadoLabel = uilabel(app.Panel_2);
            app.PodwjnewahadoLabel.Text = 'Podwójne wahadło';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = view

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end